classdef Im2meshGUI_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Panel_5                         matlab.ui.container.Panel
        Hyperlink3_2                    matlab.ui.control.Hyperlink
        Hyperlink3                      matlab.ui.control.Hyperlink
        CiteAsTextArea                  matlab.ui.control.TextArea
        CiteAsTextAreaLabel             matlab.ui.control.Label
        OpenTutorialButton              matlab.ui.control.Button
        DevelopedbyJiexianMamjx0799gmailcomLabel  matlab.ui.control.Label
        Generatefiniteelementmeshbasedon2DmultiphaseimageLabel  matlab.ui.control.Label
        v22020192025Label               matlab.ui.control.Label
        Im2mesh_GUILabel                matlab.ui.control.Label
        PristineBoundariesPanel         matlab.ui.container.Panel
        ViewBoundaryinNewWindowButton_3  matlab.ui.control.Button
        NumberofVerticesEditField       matlab.ui.control.NumericEditField
        NumberofVerticesEditFieldLabel  matlab.ui.control.Label
        UIAxes_PolyO                    matlab.ui.control.UIAxes
        SegmentedImagePanel             matlab.ui.container.Panel
        NumberofPhasesEditField         matlab.ui.control.NumericEditField
        NumberofPhasesEditFieldLabel    matlab.ui.control.Label
        ImageAxes_2                     matlab.ui.control.UIAxes
        ImagePanel                      matlab.ui.container.Panel
        FileNameEditField               matlab.ui.control.EditField
        FileNameEditFieldLabel          matlab.ui.control.Label
        WidthEditField                  matlab.ui.control.NumericEditField
        WidthEditFieldLabel             matlab.ui.control.Label
        HeightEditField                 matlab.ui.control.NumericEditField
        HeightEditFieldLabel            matlab.ui.control.Label
        ImageAxes                       matlab.ui.control.UIAxes
        SmoothedBoundariesPanel         matlab.ui.container.Panel
        ViewBoundaryinNewWindowButton   matlab.ui.control.Button
        ComparewithPristineBoundaryButton_2  matlab.ui.control.Button
        UIAxes_Smooth                   matlab.ui.control.UIAxes
        MeshPanel                       matlab.ui.container.Panel
        QMeanEditField                  matlab.ui.control.NumericEditField
        QMeanEditFieldLabel             matlab.ui.control.Label
        NumberofElementsEditField       matlab.ui.control.NumericEditField
        NumberofElementsEditFieldLabel  matlab.ui.control.Label
        ViewMeshinNewWindowButton       matlab.ui.control.Button
        EvaluateMeshQualityButton       matlab.ui.control.Button
        UIAxes_Mesh                     matlab.ui.control.UIAxes
        SimplifiedBoundariesPanel       matlab.ui.container.Panel
        NumberofVerticesEditField_3     matlab.ui.control.NumericEditField
        NumberofVerticesEditField_2Label  matlab.ui.control.Label
        ViewBoundaryinNewWindowButton_2  matlab.ui.control.Button
        ComparewithPristineBoundaryButton  matlab.ui.control.Button
        UIAxes_Simpfy                   matlab.ui.control.UIAxes
        Step7SelectPhaseOptionalPanel   matlab.ui.container.Panel
        UITable                         matlab.ui.control.Table
        Step9ExportMeshPanel            matlab.ui.container.Panel
        Panel_4                         matlab.ui.container.Panel
        DirectoryEditField              matlab.ui.control.EditField
        SelecttheExportFolderButton     matlab.ui.control.Button
        Panel_3                         matlab.ui.container.Panel
        QuadraticEditField              matlab.ui.control.EditField
        QuadraticEditFieldLabel         matlab.ui.control.Label
        ElementTypeLabel                matlab.ui.control.Label
        StatusLamp_ExpInp_Sect          matlab.ui.control.Lamp
        StatusLamp_5Label_4             matlab.ui.control.Label
        ExportasinpfileMultisectionsButton  matlab.ui.control.Button
        NastranbulkdataforCOMSOLLabel   matlab.ui.control.Label
        StatusLamp_ExpBdf               matlab.ui.control.Lamp
        StatusLamp_5Label_3             matlab.ui.control.Label
        ExportasbdforinpfileLabel       matlab.ui.control.Label
        LinearEditField                 matlab.ui.control.EditField
        ElementTypeforinpfileLabel      matlab.ui.control.Label
        ExportasbdffileButton           matlab.ui.control.Button
        ScaleofaPixelLabel              matlab.ui.control.Label
        PrecisionofNodeCoordinatesEditField  matlab.ui.control.NumericEditField
        PrecisionofNodeCoordinatesEditFieldLabel  matlab.ui.control.Label
        dyEditField                     matlab.ui.control.NumericEditField
        dyEditFieldLabel                matlab.ui.control.Label
        dxEditField                     matlab.ui.control.NumericEditField
        dxEditFieldLabel                matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        StatusLamp_ExpExcel             matlab.ui.control.Lamp
        StatusLamp_5Label_5             matlab.ui.control.Label
        ExportasExcelfileButton         matlab.ui.control.Button
        forMATLABLabel                  matlab.ui.control.Label
        StatusLamp_ExpMat               matlab.ui.control.Lamp
        StatusLamp_5Label_2             matlab.ui.control.Label
        ExportasmatfileButton           matlab.ui.control.Button
        Step4SearchControlPointsPanel   matlab.ui.container.Panel
        StatusLamp_CtrlP                matlab.ui.control.Lamp
        StatusLamp_2Label               matlab.ui.control.Label
        NumberofControlPointsEditField  matlab.ui.control.NumericEditField
        NumberofControlPointsEditFieldLabel  matlab.ui.control.Label
        NeedtoAvoidSharpCornerSwitchLabel  matlab.ui.control.Label
        NeedtoAvoidSharpCornerSwitch    matlab.ui.control.Switch
        SearchLabelControlPointsButton  matlab.ui.control.Button
        Step3ExtractBoundariesfromImagePanel  matlab.ui.container.Panel
        StatusLamp_Extract              matlab.ui.control.Lamp
        StatusLampLabel                 matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        ChooseImageforExtractionSwitch  matlab.ui.control.Switch
        ChooseInputImagefromLabel       matlab.ui.control.Label
        ExtractBoundaryButton           matlab.ui.control.Button
        Step5SmoothBoundaryOptionalPanel  matlab.ui.container.Panel
        ThresholdforNumofVerticesEditField_2  matlab.ui.control.NumericEditField
        ThresholdforNumofVerticesEditField_2Label  matlab.ui.control.Label
        StatusLamp_Smooth               matlab.ui.control.Lamp
        StatusLamp_3Label               matlab.ui.control.Label
        ThresholdforNumofTurningPointsEditField  matlab.ui.control.NumericEditField
        ThresholdforNumofTurningPointsEditFieldLabel  matlab.ui.control.Label
        IterationsEditField             matlab.ui.control.NumericEditField
        IterationsEditFieldLabel        matlab.ui.control.Label
        MuEditField                     matlab.ui.control.NumericEditField
        MuEditFieldLabel                matlab.ui.control.Label
        LambdaEditField                 matlab.ui.control.NumericEditField
        LambdaEditFieldLabel            matlab.ui.control.Label
        SmoothButton                    matlab.ui.control.Button
        Step8SelectmeshgeneratorLabel   matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        MESH2DTab                       matlab.ui.container.Tab
        StatusLamp_MESH2D               matlab.ui.control.Lamp
        StatusLamp_6Label               matlab.ui.control.Label
        ChooseMeshingAlgorithmSwitch    matlab.ui.control.Switch
        ChooseMeshingAlgorithmSwitchLabel  matlab.ui.control.Label
        MeshingButton_2                 matlab.ui.control.Button
        Hyperlink                       matlab.ui.control.Hyperlink
        ScalarGradientlimitforMeshEditField  matlab.ui.control.NumericEditField
        ScalarGradientlimitforMeshEditFieldLabel  matlab.ui.control.Label
        MaxMeshsizeEditField            matlab.ui.control.NumericEditField
        MaxMeshsizeEditFieldLabel       matlab.ui.control.Label
        generateMeshTab                 matlab.ui.container.Tab
        StatusLamp_genMesh              matlab.ui.control.Lamp
        StatusLamp_5Label               matlab.ui.control.Label
        MeshingButton                   matlab.ui.control.Button
        Hyperlink2                      matlab.ui.control.Hyperlink
        MinMeshEdgeLengthEditField      matlab.ui.control.NumericEditField
        MinMeshEdgeLengthEditFieldLabel  matlab.ui.control.Label
        MaxMeshEdgeLengthEditField      matlab.ui.control.NumericEditField
        MaxmeshedgelengthLabel          matlab.ui.control.Label
        MeshGrowthRateEditField         matlab.ui.control.NumericEditField
        MeshGrowthRateEditFieldLabel    matlab.ui.control.Label
        GmshTab                         matlab.ui.container.Tab
        StatusLamp_GmshExp              matlab.ui.control.Lamp
        StatusLamp_5Label_8             matlab.ui.control.Label
        StatusLamp_Gmsh                 matlab.ui.control.Lamp
        StatusLamp_5Label_7             matlab.ui.control.Label
        ExporttoFileButton              matlab.ui.control.Button
        MeshingButton_4                 matlab.ui.control.Button
        FileNameSuffixforOutputEditField  matlab.ui.control.EditField
        FileNameSuffixforOutputEditFieldLabel  matlab.ui.control.Label
        RecombinationAlgorithmSpinner   matlab.ui.control.Spinner
        RecombinationAlgorithmSpinnerLabel  matlab.ui.control.Label
        RecombineAllSpinner             matlab.ui.control.Spinner
        RecombineAllSpinnerLabel        matlab.ui.control.Label
        ElementOrderSpinner             matlab.ui.control.Spinner
        ElementOrderSpinnerLabel        matlab.ui.control.Label
        AlgorithmSpinner                matlab.ui.control.Spinner
        AlgorithmSpinnerLabel           matlab.ui.control.Label
        MeshSizeMaxEditField            matlab.ui.control.NumericEditField
        MeshSizeMaxEditFieldLabel       matlab.ui.control.Label
        MeshSizeMinEditField            matlab.ui.control.NumericEditField
        MeshSizeMinEditFieldLabel       matlab.ui.control.Label
        gmshLocationEditField           matlab.ui.control.EditField
        LocategmshexeButton             matlab.ui.control.Button
        pixelMeshTab                    matlab.ui.container.Tab
        Hyperlink4                      matlab.ui.control.Hyperlink
        StatusLamp_pixelMesh            matlab.ui.control.Lamp
        StatusLamp_5Label_6             matlab.ui.control.Label
        MeshingButton_3                 matlab.ui.control.Button
        Step6SimplifyBoundaryPanel      matlab.ui.container.Panel
        StatusLamp_Simpfy               matlab.ui.control.Lamp
        StatusLamp_4Label               matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        ThresholdforNumofVerticesEditField  matlab.ui.control.NumericEditField
        ThresholdforNumofVerticesEditFieldLabel  matlab.ui.control.Label
        ChooseBoundaryforSimplificationSwitch  matlab.ui.control.Switch
        ChooseBoundaryforSimplificationSwitchLabel  matlab.ui.control.Label
        SimplifyButton                  matlab.ui.control.Button
        ToleranceEditField              matlab.ui.control.NumericEditField
        ToleranceEditFieldLabel         matlab.ui.control.Label
        Step2ImageSegmentationOptionalPanel  matlab.ui.container.Panel
        StatusLamp_Segment              matlab.ui.control.Lamp
        StatusLampLabel_2               matlab.ui.control.Label
        NumberofPhasesYourTargetEditField  matlab.ui.control.NumericEditField
        howmanyphasesinyourimageLabel   matlab.ui.control.Label
        SegmentationOtsuMethodButton    matlab.ui.control.Button
        Step1ImportImagePanel           matlab.ui.container.Panel
        PepperButton                    matlab.ui.control.Button
        ShapeButton                     matlab.ui.control.Button
        LoadExampleImagesLabel          matlab.ui.control.Label
        PhasesButton                    matlab.ui.control.Button
        CircleButton                    matlab.ui.control.Button
        SimpleButton                    matlab.ui.control.Button
        orLabel                         matlab.ui.control.Label
        kumamonButton                   matlab.ui.control.Button
        NumberofPhasesinLoadedImageEditField  matlab.ui.control.NumericEditField
        NumberofPhasesinLoadedImageEditFieldLabel  matlab.ui.control.Label
        ImportfromFolderButton          matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        StatusLamp_4                    matlab.ui.control.Lamp
        StatusLamp_4Label_5             matlab.ui.control.Label
        StatusLamp_3                    matlab.ui.control.Lamp
        StatusLamp_4Label_4             matlab.ui.control.Label
        StatusLamp_2                    matlab.ui.control.Lamp
        StatusLamp_4Label_3             matlab.ui.control.Label
        StatusLamp_1                    matlab.ui.control.Lamp
        StatusLamp_4Label_2             matlab.ui.control.Label
        MeaningofStatusColorLabel       matlab.ui.control.Label
    end

    
    properties (Access = private)
        im_o    % input image
        im_seg  % segmented image
        im      % image for meshing

        tf_avoid_sharp_corner   % boolean
        
        boundsRaw           % pristine boundaries
        boundsCtrlP         % boundaries with control point
        boundsSmooth        % smoothed boundaries
        boundsNeedSimpfy    % boundaries that need simplify
        boundsSimpfied      % simplified boundaries
        
        vert
        ele
        tnum
        vert2
        ele2
        
        mesh1   % FEMesh object for linear elements
        mesh2   % FEMesh object for quadratic elements
        
        meshG   % result of gmsh

        path_to_gmsh    % gmsh.exe location
        dir_name     % directory name for general output
    end
    
    methods (Access = private)
        
        function readUpdateImage(app, path_file_name)
            % read image & update image axes
            % ------------------------------------------------------------
            try
                app.im_o = imread(path_file_name);
            catch ME
                % If problem reading image, display error message
                uialert(app.UIFigure,ME.message,"Image Error");
                return;
            end
            
            % ------------------------------------------------------------
            % convert image to grayscale
            if size(app.im_o,3) == 3  
                app.im_o = rgb2gray( app.im_o );
            end
            
            % show image
	        imagesc( app.ImageAxes, app.im_o );
            app.im = app.im_o;
            
            % show file name
            [ ~, name, ext ] = fileparts( path_file_name );
            app.FileNameEditField.Value = [ name, ext ];
            
            % show number of intensity level
            intensity = unique( app.im_o );    % vector
            num_phase = size( intensity, 1 );
            app.NumberofPhasesinLoadedImageEditField.Value = num_phase;

            % show size
            app.HeightEditField.Value = size( app.im_o, 1 );
            app.WidthEditField.Value = size( app.im_o, 2 );

            % ------------------------------------------------------------
            % initialze when importing new image
            % clear variables
            app.im_seg = [];
            app.boundsRaw = [];           
            app.boundsCtrlP = [];         
            app.boundsSmooth = [];        
            app.boundsNeedSimpfy = [];    
            app.boundsSimpfied = [];   

            app.vert = [];
            app.ele = [];
            app.tnum = [];
            app.vert2 = [];
            app.ele2 = [];
            app.mesh1 = [];
            app.mesh2 = [];
            app.UITable.Data = [];

            % clear plots
            cla( app.ImageAxes_2 )
            cla( app.UIAxes_PolyO )
            cla( app.UIAxes_Smooth )
            cla( app.UIAxes_Simpfy )
            cla( app.UIAxes_Mesh )

            % clear lamps
            app.StatusLamp_Segment.Color = [.9 .9 .9];
            app.StatusLamp_Extract.Color = [.9 .9 .9];
            app.StatusLamp_CtrlP.Color = [.9 .9 .9];
            app.StatusLamp_Smooth.Color = [.9 .9 .9];
            app.StatusLamp_Simpfy.Color = [.9 .9 .9];
            app.StatusLamp_MESH2D.Color = [.9 .9 .9];
            app.StatusLamp_genMesh.Color = [.9 .9 .9];
            app.StatusLamp_pixelMesh.Color = [.9 .9 .9];
            app.StatusLamp_Gmsh.Color = [.9 .9 .9];
            app.StatusLamp_GmshExp.Color = [.9 .9 .9];
            app.StatusLamp_ExpMat.Color = [.9 .9 .9];
            app.StatusLamp_ExpExcel.Color = [.9 .9 .9];
            app.StatusLamp_ExpBdf.Color = [.9 .9 .9];
            % app.StatusLamp_ExpInp_Part.Color = [.9 .9 .9];
            app.StatusLamp_ExpInp_Sect.Color = [.9 .9 .9];
            
            % clear some txt field
            app.NumberofControlPointsEditField.Value = 0;
            app.NumberofPhasesEditField.Value = 0;
            app.NumberofVerticesEditField.Value = 0;
            app.NumberofVerticesEditField_3.Value = 0;
            app.NumberofElementsEditField.Value = 0;
            app.QMeanEditField.Value = 0;
            % ------------------------------------------------------------
        end
        
        function plotBounds(app, axhdle, bounds)
            % plot boundaryies
            % usage 1:  plotBounds(app, [], app.boundsSimpfied );
            % usage 2:  plotBounds(app, app.UIAxes_Simpfy, bounds);

            b = bounds;
            
            if isempty(axhdle)
                % if UI axes handlle is empty, plot in a new window

                figure('color','white');
                hold on
                axis image off;
                
                for i = 1: length(b)
                    for j = 1: length(b{i})
                        poly = b{i}{j};
                        plot( poly(:,1), poly(:,2) );
                    end
                end
                hold off
            else
                % if UI axes handlle isn't empty, plot in UI axes handlle
                cla( axhdle )
                hold( axhdle, "on" )
                axis( axhdle, "equal")
    
                for i = 1: length(b)
	                for j = 1: length(b{i})
		                poly = b{i}{j};
		                plot( axhdle, poly(:,1), poly(:,2) );
	                end
                end
                hold( axhdle, "off" )
            end
        end

        function plotMeshes(app, axhdle )
            % plot meshes
            % usage 1:  plotMeshes(app, [] );
            % usage 2:  plotMeshes(app, app.UIAxes_Mesh );

            % check input
            ele_wid = size(app.ele,2);
            if ele_wid == 3         % linear triangle
                range_vec = 1:3;
            elseif ele_wid == 6     % quadratic triangle
                range_vec = [1 4 2 5 3 6];
            elseif ele_wid == 4     % linear quadrilateral
                range_vec = 1:4;
            elseif ele_wid == 8     % quadratic quadrilateral
                range_vec = [1 5 2 6 3 7 4 8];
            else
                error("ele - wrong size")
            end

            if isempty(axhdle)
                % if UI axes handlle is empty, plot in a new window
                figure;
                hold on; 
                axis image off;
            else
                % if UI axes handlle isn't empty, plot in UI axes handlle
                cla( axhdle )
                hold( axhdle, "on" )
                axis( axhdle,"equal")
            end

            tvalue = unique( app.tnum );
            num_phase = length( tvalue );
            
            % setup color
            if num_phase == 1
                col = 0.98;
            elseif num_phase > 1
                col = 0.3: 0.68/(num_phase-1): 0.98;
            else
                error("num_phase < 1")
            end
            
            if isempty(axhdle)
                for i = 1: num_phase
                    phasecode = tvalue(i);
                    patch('faces',app.ele( app.tnum==phasecode, range_vec ), ...
                        'vertices',app.vert, ...
                        'facecolor',[ col(i), col(i), col(i) ], ...
                        'edgecolor',[.1,.1,.1], ...
                        'EdgeAlpha', 0.5 ...
                        );
                end
                hold off
            else
                for i = 1: num_phase
                    phasecode = tvalue(i);
                    patch( axhdle, ...
                            'faces', app.ele( app.tnum == phasecode, range_vec ), ...
                            'vertices', app.vert, ...
                            'facecolor',[ col(i), col(i), col(i) ], ...
                            'edgecolor',[.1,.1,.1], ...
                            'EdgeAlpha', 0.3 ...
                        );
                    % Set 'EdgeAlpha'=0.3, to make edges more easier to view
                    % in GUI
                end
                hold( axhdle, "off" )
            end

        end
        

        function send2TextArea( app, msg_str )
            dt = datetime;
            msg_with_time = sprintf('%s \n%s', msg_str, datestr(dt) );
            app.StatusTextArea.Value = msg_with_time; 
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Configure image axes
            % app.ImageAxes.Visible = "off";
            app.ImageAxes.Colormap = gray(256);
            axis(app.ImageAxes,"image");

            % app.ImageAxes_2.Visible = "off";
            app.ImageAxes_2.Colormap = gray(256);
            axis(app.ImageAxes_2,"image");
            
        end

        % Button pushed function: ImportfromFolderButton
        function ImportfromFolderButtonPushed(app, event)
            
            % Display uigetfile dialog
            filterspec = {'*.tif;*.png;*.bmp;*.gif;*.jpg','All Image Files'};
            [file, path] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(path))
                path_file_name = [path file];
                readUpdateImage(app,path_file_name);
            end

        end

        % Button pushed function: kumamonButton
        function kumamonButtonPushed(app, event)
            % import image
            fname = 'kumamon.tif';
            readUpdateImage(app,fname);
        end

        % Button pushed function: CircleButton
        function CircleButtonPushed(app, event)
            % import image
            fname = 'Circle.tif';
            readUpdateImage(app,fname);          
        end

        % Button pushed function: SimpleButton
        function SimpleButtonPushed(app, event)
            % import image
            fname = 'Simple.tif';
            readUpdateImage(app,fname);
        end

        % Button pushed function: PhasesButton
        function PhasesButtonPushed(app, event)
            % import image
            fname = '7 Phases.png';
            readUpdateImage(app,fname);
        end

        % Button pushed function: PepperButton
        function PepperButtonPushed(app, event)
            % import image
            fname = 'Pepper.png';
            readUpdateImage(app,fname);
        end

        % Button pushed function: ShapeButton
        function ShapeButtonPushed(app, event)
            % import image
            fname = 'Shape.tif';
            readUpdateImage(app,fname);            
        end

        % Button pushed function: SegmentationOtsuMethodButton
        function SegmentationOtsuMethodButtonPushed(app, event)
            % input: app.im_o, app.NumberofPhasesYourTargetEditField
            try
                % ------------------------------------------------------------
                app.StatusLamp_Segment.Color = 'blue';
                % Pause to ensure UI updates
                drawnow;
    
                % ------------------------------------------------------------
                % how many phases you desire
                num_level = app.NumberofPhasesYourTargetEditField.Value; 
                
                % check input app.im_o
                if isempty( app.im_o ) 
                    error("Please import an image before segmentation.")
                end

                % get the number of phases in the pritine image
                intensity = unique( app.im_o );    % vector
                num_phase_pristine = size( intensity, 1 );
                
                % check input num_level
                if num_level > num_phase_pristine
                    error("Please enter a smaller value in Number of Phases. " + ...
                        "Or skip Step2.");
                elseif num_level == num_phase_pristine
                    app.im_seg = app.im_o;
                else
                    % normal case
                    % perform Otsu multi-thresh with matlab function
                    thresh = multithresh( app.im_o, num_level-1 ); 
                    im_temp = imquantize( app.im_o, thresh );
                    im_temp = mat2gray( im_temp );
                    im_temp = uint8( im_temp * 255 );
                    app.im_seg = im_temp;
                end
                
                % show result
                imagesc( app.ImageAxes_2, app.im_seg );
    
                % show number of intensity level
                app.NumberofPhasesEditField.Value = size( unique( app.im_seg ), 1 );
                % ------------------------------------------------------------
                % show status
                pause(0.5)
                app.StatusLamp_Segment.Color = 'green';
                % ------------------------------------------------------------
            catch ME
                app.StatusLamp_Segment.Color = 'red';
	            uialert( app.UIFigure, getReport(ME), ...
			            "Error Message", "Interpreter", "html" );
            end
        end

        % Button pushed function: ExtractBoundaryButton
        function ExtractBoundaryButtonPushed(app, event)
            % input: app.im_o, app.im_seg, app.ChooseImageforExtractionSwitch

            % set limit for number of phases in image
            MAX_NUM_PHASE = 25;

            try
                % ------------------------------------------------------------                
                app.StatusLamp_Extract.Color = 'blue';
                % Pause to ensure UI updates
                drawnow;
                
                % ------------------------------------------------------------
                % Obatin input image
                % Check switch value to determine which images is the input
                value = app.ChooseImageforExtractionSwitch.Value;
                if value == "From Step1"
                    if isempty( app.im_o ) 
                        error("Please import an image before extraction.");
                    end
                    app.im = app.im_o;
                elseif value == "From Step2"
                    if isempty( app.im_seg )
                        error("Segmented image from Step2 is not availabe.");
                    end
                    app.im = app.im_seg;
                else
                    error("bad value at Switch");
                end
    
                % check input image
                intensity = unique( app.im );    % vector
                num_phase = size( intensity, 1 );
                if num_phase > MAX_NUM_PHASE
                    error("num_phase > 25  - Extraction may fail. " + ...
                        "Please perform segmentation to your image before Step3.");
                end
                
                % ------------------------------------------------------------
                % image to polygonal boundaries
                bounds = im2Bounds( app.im );
                app.boundsRaw = bounds;
                
                % get total number of vertices
                app.NumberofVerticesEditField.Value = totalNumVertex( bounds );
                
                % plot
                plotBounds(app, app.UIAxes_PolyO, bounds);

                % ------------------------------------------------------------
                % construct a table for phase selection 
                % initialize
                intensity = unique( app.im );           % column vector
                num_phase = size( intensity, 1 );
                tf_need_mesh = true( num_phase, 1);     % boolean vector
                
                % calculate the area perccentage of grayscale in image
                percent_pixel = getPixelPercent( app.im );

                % 3 columns for table
                t = table( intensity, tf_need_mesh, percent_pixel );
                app.UITable.Data = t;   % store table

                % ------------------------------------------------------------
                % show status
                pause(0.5)
                app.StatusLamp_Extract.Color = 'green';

                % ------------------------------------------------------------
            catch ME
                app.StatusLamp_Extract.Color = 'red';
                uialert( app.UIFigure, getReport(ME), ...
                        "Error Message", "Interpreter", "html" );
            end
        end

        % Button pushed function: SearchLabelControlPointsButton
        function SearchLabelControlPointsButtonPushed(app, event)
            % input: app.boundsRaw, app.NeedtoAvoidSharpCornerSwitch
            try
                % ------------------------------------------------------------
                app.StatusLamp_CtrlP.Color = 'blue';
                % Pause to ensure UI updates
                drawnow;
    
                % ------------------------------------------------------------
                % Check switch value to determine tf_avoid_sharp_corner
                value = app.NeedtoAvoidSharpCornerSwitch.Value;
                if value == "No"
                    app.tf_avoid_sharp_corner = false;
                elseif value == "Yes"
                    app.tf_avoid_sharp_corner = true;
                else
                    error("bad value at Switch")
                end

                % check input app.boundsRaw
                if isempty( app.boundsRaw ) 
	                error("Please extract boundaries before searching control points.");
                end
                
                % ------------------------------------------------------------
                bounds = getCtrlPnts( app.boundsRaw, app.tf_avoid_sharp_corner, size(app.im) );
                app.boundsCtrlP = bounds;
                
                % get total number of control points
                app.NumberofControlPointsEditField.Value = totalNumCtrlPnt( bounds );
                
                % ------------------------------------------------------------
                % show status
                pause(0.5)
                app.StatusLamp_CtrlP.Color = 'green';
            catch ME
                app.StatusLamp_CtrlP.Color = 'red';
	            uialert( app.UIFigure, getReport(ME), ...
			            "Error Message", "Interpreter", "html" );
            end
        end

        % Button pushed function: SmoothButton
        function SmoothButtonPushed(app, event)
            % input: app.boundsCtrlP, app.LambdaEditField, app.MuEditField
            % app.IterationsEditField, app.ThresholdforNumofTurningPointsEditField
            try
                % ------------------------------------------------------------
                app.StatusLamp_Smooth.Color = 'blue';
                % Pause to ensure UI updates
                drawnow;
    
                % ------------------------------------------------------------
                % check input app.boundsCtrlP
                if isempty( app.boundsCtrlP ) 
	                error("Please search control points before smoothing");
                end

                % parameters for smoothBounds
                lambda = app.LambdaEditField.Value;
                mu = app.MuEditField.Value;
                iters = app.IterationsEditField.Value;
                % threshlod for number of turning point
                threshold_turning = app.ThresholdforNumofTurningPointsEditField.Value;
                % threshlod for number of vertices
                threshold_vert = app.ThresholdforNumofVerticesEditField_2.Value;
                
                bounds = smoothBounds( app.boundsCtrlP, lambda, mu, iters, ...
                                    threshold_turning, threshold_vert  );
                app.boundsSmooth = bounds;
    
                % plot bounds
                plotBounds(app, app.UIAxes_Smooth, bounds);
                % ------------------------------------------------------------
                % show status
                pause(0.5)
                app.StatusLamp_Smooth.Color = 'green';
            catch ME
                app.StatusLamp_Smooth.Color = 'red';
	            uialert( app.UIFigure, getReport(ME), ...
			            "Error Message", "Interpreter", "html" );
            end
        end

        % Button pushed function: SimplifyButton
        function SimplifyButtonPushed(app, event)
            % input: app.boundsCtrlP, app.boundsSmooth, app.ToleranceEditField
            % app.ChooseBoundaryforSimplificationSwitch
            % app.ThresholdforNumofVerticesEditField
            try
                % ------------------------------------------------------------
                app.StatusLamp_Simpfy.Color = 'blue';
                % Pause to ensure UI updates
                drawnow;
                
                % ------------------------------------------------------------
                % Obtain input polygonal boundaries
                % Check switch value to determine which variables is input
                value = app.ChooseBoundaryforSimplificationSwitch.Value;
                if value == "From Step4"
                    if isempty( app.boundsCtrlP ) 
	                    error("Result from Step4 is not available");
                    end
                    app.boundsNeedSimpfy = app.boundsCtrlP;
                elseif value == "From Step5"
                    if isempty( app.boundsSmooth ) 
	                    error("Result from Step5 is not available");
                    end                    
                    app.boundsNeedSimpfy = app.boundsSmooth;
                else
                    error("bad value at Switch")
                end
    
                % ------------------------------------------------------------
                % parameters for simplifyBounds
                tolerance = app.ToleranceEditField.Value;
                threshold = app.ThresholdforNumofVerticesEditField.Value;
    
                % simplify polygon boundary
                bounds = simplifyBounds( app.boundsNeedSimpfy, tolerance, threshold );
                bounds = delZeroAreaPoly( bounds );
            
                % clear up redundant vertices
                % only control points and knick-points will remain
                bounds = getCtrlPnts( bounds, false );
                bounds = simplifyBounds( bounds, eps );
                app.boundsSimpfied = bounds;
                
                % get total number of vertices
                app.NumberofVerticesEditField_3.Value = totalNumVertex( bounds );
                
                % plot bounds
                plotBounds(app, app.UIAxes_Simpfy, bounds );
                
                % ------------------------------------------------------------
                % show status
                pause(0.5)
                app.StatusLamp_Simpfy.Color = 'green';
            catch ME
                app.StatusLamp_Simpfy.Color = 'red';
	            uialert( app.UIFigure, getReport(ME), ...
			            "Error Message", "Interpreter", "html" );
            end
        end

        % Button pushed function: MeshingButton
        function MeshingButtonPushed(app, event)
            % generate meshes using Matlab built-in function generateMesh
            % input: app.boundsSimpfied
            try
                % ------------------------------------------------------------
                app.StatusLamp_genMesh.Color = 'blue';
                % Pause to ensure UI updates
                drawnow;
    
                % ------------------------------------------------------------
                % check input app.boundsSimpfied
                if isempty( app.boundsSimpfied )
	                error("Please simplify boundaries before meshing. " + ...
                        "If your boundaries don't need simplification, " + ...
                        "set the value of parameter tolerance to 0." );
                end

                % select material phases that need meshing
                t = app.UITable.DisplayData;
                bounds = app.boundsSimpfied( t.tf_need_mesh );

                % prepare data
                [ node_cell, edge_cell ] = getPolyNodeEdge( bounds );
                pcell = bound2polyshape( bounds );
    
                % parameters
                hgrad = app.MeshGrowthRateEditField.Value;
                hmax = app.MaxMeshEdgeLengthEditField.Value;
                hmin = app.MinMeshEdgeLengthEditField.Value;
                
                % generate mesh
                [ app.vert, app.ele, app.tnum, app.vert2, app.ele2, app.mesh1, app.mesh2 ] = ...
                    poly2meshBuiltIn( node_cell, edge_cell, pcell, hgrad, hmax, hmin );
                
                % show num of triangles
                app.NumberofElementsEditField.Value = size( app.ele, 1 );
    
                plotMeshes(app, app.UIAxes_Mesh )

                % show mesh quality
                q_score = triscr2( app.vert, app.ele );
                app.QMeanEditField.Value = mean( q_score );
                % ------------------------------------------------------------
                % show status
                pause(0.5)
                app.StatusLamp_genMesh.Color = 'green';
                % ------------------------------------------------------------
            catch ME
                app.StatusLamp_genMesh.Color = 'red';
	            uialert( app.UIFigure, getReport(ME), ...
			            "Error Message", "Interpreter", "html" );
            end
        end

        % Button pushed function: MeshingButton_2
        function MeshingButton_2Pushed(app, event)
            % generate meshes using MESH2D
            % addpath('mesh2d-master')
            
            try
                % ------------------------------------------------------------
                app.StatusLamp_MESH2D.Color = 'blue';
                % Pause to ensure UI updates
                drawnow;
    
                % ------------------------------------------------------------
                % check input app.boundsSimpfied
                if isempty( app.boundsSimpfied )
	                error("Please simplify boundaries before meshing. " + ...
                        "If your boundaries don't need simplification, " + ...
                        "set the value of parameter tolerance to 0." );
                end

                % select material phases that need meshing
                t = app.UITable.DisplayData;
                bounds = app.boundsSimpfied( t.tf_need_mesh );
                
                % prepare data
                [ node_cell, edge_cell ] = getPolyNodeEdge( bounds );
    
                % parameters
                hmax = app.MaxMeshsizeEditField.Value;
                grad_limit = app.ScalarGradientlimitforMeshEditField.Value;
                mesh_kind = app.ChooseMeshingAlgorithmSwitch.Value;
    
                % generate mesh
                [ app.vert, app.ele, app.tnum, app.vert2, app.ele2 ] = ...
                    poly2mesh( node_cell, edge_cell, hmax, mesh_kind, grad_limit );
                app.mesh1 = []; app.mesh2 = [];
                
                % show num of triangles
                app.NumberofElementsEditField.Value = size( app.ele, 1 );
                
                plotMeshes(app, app.UIAxes_Mesh )
                
                % show mesh quality
                q_score = triscr2( app.vert, app.ele );
                app.QMeanEditField.Value = mean( q_score );
                % ------------------------------------------------------------
                % show status
                pause(0.5)
                app.StatusLamp_MESH2D.Color = 'green';
                % ------------------------------------------------------------
            catch ME
                app.StatusLamp_MESH2D.Color = 'red';
	            uialert( app.UIFigure, getReport(ME), ...
			            "Error Message", "Interpreter", "html" );
            end                
        end

        % Button pushed function: MeshingButton_3
        function MeshingButton_3Pushed(app, event)
            % generate meshes using pixelMesh
            
            try
                % ------------------------------------------------------------
                app.StatusLamp_pixelMesh.Color = 'blue';
                % Pause to ensure UI updates
                drawnow;
                
                % ------------------------------------------------------------
                % check input app.im
                if isempty( app.im ) 
	                error("Please extract boundaries before meshing.");
                end

                % select material phases that need meshing
                t = app.UITable.DisplayData;
                opt.select_phase = find( t.tf_need_mesh );
                
                % generate mesh
                [ app.vert, app.ele, app.tnum, app.vert2, app.ele2 ] = ...
                                                pixelMesh( app.im, opt );
                
                % show num of element
                app.NumberofElementsEditField.Value = size( app.ele, 1 );
                
                plotMeshes(app, app.UIAxes_Mesh )
                
                % show mesh quality
                app.QMeanEditField.Value = 0;
                % ------------------------------------------------------------
                % show status
                pause(0.5)
                app.StatusLamp_pixelMesh.Color = 'green';
                % ------------------------------------------------------------
            catch ME
                app.StatusLamp_pixelMesh.Color = 'red';
	            uialert( app.UIFigure, getReport(ME), ...
			            "Error Message", "Interpreter", "html" );
            end             
        end

        % Button pushed function: ViewBoundaryinNewWindowButton_3
        function ViewBoundaryinNewWindowButton_3Pushed(app, event)
            plotBounds( app, [], app.boundsRaw );
        end

        % Button pushed function: ViewBoundaryinNewWindowButton
        function ViewBoundaryinNewWindowButtonPushed(app, event)
            plotBounds( app, [], app.boundsSmooth );
        end

        % Button pushed function: ViewBoundaryinNewWindowButton_2
        function ViewBoundaryinNewWindowButton_2Pushed(app, event)
            plotBounds( app, [], app.boundsSimpfied );
        end

        % Button pushed function: ViewMeshinNewWindowButton
        function ViewMeshinNewWindowButtonPushed(app, event)
            try
                % check input
                if isempty(app.vert) || isempty(app.ele) || isempty(app.tnum)
	                error("Meshing result not available")
                end

                plotMeshes( app, [] );
            catch ME
	            uialert( app.UIFigure, getReport(ME), ...
			            "Error Message", "Interpreter", "html" );
            end
        end

        % Button pushed function: ComparewithPristineBoundaryButton_2
        function ComparewithPristineBoundaryButton_2Pushed(app, event)
            % compare app.boundsSmooth with app.boundsRaw
            figure('color','white');
            title('Red - Smoothed Boundaries.      Blue - Pristine')
            hold on
            axis image off;
            
            b = app.boundsRaw;
            for i = 1: length(b)
                for j = 1: length(b{i})
                    poly = b{i}{j};
                    plot( poly(:,1), poly(:,2), 'b' );
                end
            end

            b = app.boundsSmooth;
            for i = 1: length(b)
                for j = 1: length(b{i})
                    poly = b{i}{j};
                    plot( poly(:,1), poly(:,2), 'r' );
                end
            end

            hold off
        end

        % Button pushed function: ComparewithPristineBoundaryButton
        function ComparewithPristineBoundaryButtonPushed(app, event)
            % compare app.boundsSimpfied with app.boundsRaw
            figure('color','white');
            title('Red - Simplified Boundaries.      Blue - Pristine')
            hold on
            axis image off;
            
            b = app.boundsRaw;
            for i = 1: length(b)
                for j = 1: length(b{i})
                    poly = b{i}{j};
                    plot( poly(:,1), poly(:,2), 'b' );
                end
            end

            b = app.boundsSimpfied;
            for i = 1: length(b)
                for j = 1: length(b{i})
                    poly = b{i}{j};
                    plot( poly(:,1), poly(:,2), 'r' );
                end
            end

            hold off
        end

        % Button pushed function: EvaluateMeshQualityButton
        function EvaluateMeshQualityButtonPushed(app, event)
            try
	            % check input
                if isempty(app.vert) || isempty(app.ele) || isempty(app.tnum)
		            error("Meshing result not available")
                end

                if size(app.ele,2) == 4
                    return
                end
            
	            tricost( app.vert, app.ele, app.tnum );
            catch ME
	            uialert( app.UIFigure, getReport(ME), ...
			            "Error Message", "Interpreter", "html" );
            end
        end

        % Button pushed function: SelecttheExportFolderButton
        function SelecttheExportFolderButtonPushed(app, event)
            app.dir_name = uigetdir;

            % Make sure user didn't cancel uigetdir dialog
            if ischar(app.dir_name)
                app.DirectoryEditField.Value = app.dir_name;
            end
        end

        % Button pushed function: ExportasmatfileButton
        function ExportasmatfileButtonPushed(app, event)
            try
                % ------------------------------------------------------------
                app.StatusLamp_ExpMat.Color = 'blue';
                % Pause to ensure UI updates
                drawnow;
    
                % ------------------------------------------------------------
                % check input
                if isempty(app.dir_name)
                    error("Please select the export folder")
                end
                
                if isempty(app.vert) || isempty(app.ele) || isempty(app.tnum) ...
                        || isempty(app.ele2) || isempty(app.vert2)
                    error("Meshing result not available")
                end

                % ------------------------------------------------------------
                % setup
                path_name = app.dir_name;
                path_file_name = [ path_name, filesep, 'test.mat' ];
                
                vertL = app.vert;
                eleL = app.ele;
                pcode = app.tnum;
                vertQ = app.vert2;
                eleQ = app.ele2;

                if exist(path_file_name, 'file')
                    delete(path_file_name)
                end

                save( path_file_name, 'vertL', 'eleL', 'pcode', ...
                                      'vertQ', 'eleQ' );

                if isempty(app.mesh1) && isempty(app.mesh2)
                    % do nothing
                elseif ~isempty(app.mesh1) && ~isempty(app.mesh2)
                    meshL = app.mesh1;
                    meshQ = app.mesh2;
                    save( path_file_name, 'meshL', 'meshQ', '-append' );
                else
                    error("wierd case")
                end
                
                % ------------------------------------------------------------
                % show status
                pause(0.5)
                app.StatusLamp_ExpMat.Color = 'green';
                % ------------------------------------------------------------
            catch ME
	            app.StatusLamp_ExpMat.Color = 'red';
	            uialert( app.UIFigure, getReport(ME), ...
			            "Error Message", "Interpreter", "html" );
            end
        end

        % Button pushed function: ExportasExcelfileButton
        function ExportasExcelfileButtonPushed(app, event)
            try
                % ------------------------------------------------------------
                app.StatusLamp_ExpExcel.Color = 'blue';
                % Pause to ensure UI updates
                drawnow;
    
                % ------------------------------------------------------------
                % check input
                if isempty(app.dir_name)
                    error("Please select the export folder")
                end
                
                if isempty(app.vert) || isempty(app.ele) || isempty(app.tnum) ...
                        || isempty(app.ele2) || isempty(app.vert2)
                    error("Meshing result not available")
                end

                % ------------------------------------------------------------
                % setup
                path_name = app.dir_name;
                path_file_name = [ path_name, filesep, 'test.xls' ];
                
                vertL = app.vert;
                eleL = app.ele;
                pcode = app.tnum;
                vertQ = app.vert2;
                eleQ = app.ele2;

                if exist(path_file_name, 'file')
                    delete(path_file_name)
                end
                
                if exist('writematrix', 'file') == 2
                    writematrix( vertL, path_file_name,'Sheet','vertL');
                    writematrix( eleL, path_file_name,'Sheet','eleL','WriteMode','append');
                    writematrix( pcode, path_file_name,'Sheet','pcode','WriteMode','append');
                    writematrix( vertQ, path_file_name,'Sheet','vertQ','WriteMode','append');
                    writematrix( eleQ, path_file_name,'Sheet','eleQ','WriteMode','append');
                else
                    xlswrite( path_file_name, vertL, 'vertL' );
                    xlswrite( path_file_name, eleL, 'eleL' );
                    xlswrite( path_file_name, pcode, 'pcode' );
                    xlswrite( path_file_name, vertQ, 'vertQ' );
                    xlswrite( path_file_name, eleQ, 'eleQ' );
                end
                
                % ------------------------------------------------------------
                % show status
                pause(0.5)
                app.StatusLamp_ExpExcel.Color = 'green';
                % ------------------------------------------------------------
            catch ME
	            app.StatusLamp_ExpExcel.Color = 'red';
	            uialert( app.UIFigure, getReport(ME), ...
			            "Error Message", "Interpreter", "html" );
            end
        end

        % Button pushed function: ExportasbdffileButton
        function ExportasbdffileButtonPushed(app, event)
            try
                % ------------------------------------------------------------
                app.StatusLamp_ExpBdf.Color = 'blue';
                % Pause to ensure UI updates
                drawnow;
    
                % ------------------------------------------------------------
                % check input
                if isempty(app.dir_name)
                    error("Please select the export folder")
                end
                
                if isempty(app.vert) || isempty(app.ele) || isempty(app.tnum) ...
                        || isempty(app.ele2) || isempty(app.vert2)
                    error("Meshing result not available")
                end

                % ------------------------------------------------------------
                % setup
                path_name = app.dir_name;
                path_file_name = [ path_name, filesep, 'test.bdf' ];
                
                % scale node coordinates
                vertices = app.vert;
                vertices( :, 1 ) = vertices( :, 1 ) * app.dxEditField.Value;
                vertices( :, 2 ) = vertices( :, 2 ) * app.dyEditField.Value;
                
                % parameters
                precision_nodecoor = app.PrecisionofNodeCoordinatesEditField.Value;
                    
                % get node coordinares and elements from mesh
                [ nodecoor_list, ~, ele_cell ] = getNodeEle( ...
                                            vertices, app.ele, app.tnum );

                % bdf file (Nastran bulk data)
                printBdf( nodecoor_list, ele_cell, precision_nodecoor, path_file_name );
                
                % ------------------------------------------------------------
                % show status
                pause(0.5)
                app.StatusLamp_ExpBdf.Color = 'green';
                % ------------------------------------------------------------
            catch ME
	            app.StatusLamp_ExpBdf.Color = 'red';
	            uialert( app.UIFigure, getReport(ME), ...
			            "Error Message", "Interpreter", "html" );
            end
        end

        % Button pushed function: ExportasinpfileMultisectionsButton
        function ExportasinpfileMultisectionsButtonPushed(app, event)
            try
                % ------------------------------------------------------------
                app.StatusLamp_ExpInp_Sect.Color = 'blue';
                % Pause to ensure UI updates
                drawnow;
                
                % ------------------------------------------------------------
                % check input
                if isempty(app.dir_name)
                    error("Please select the export folder")
                end
                
                if isempty(app.vert) || isempty(app.ele) || isempty(app.tnum) ...
                        || isempty(app.ele2) || isempty(app.vert2)
                    error("Meshing result not available")
                end
                
                % ------------------------------------------------------------
                % setup
                path_name = app.dir_name;
                path_file_name = [ path_name, filesep, 'test_linear.inp' ];
                % ------------------------------------------------------------
                % export linear element
                % scale node coordinates
                vertices = app.vert;
                vertices( :, 1 ) = vertices( :, 1 ) * app.dxEditField.Value;
                vertices( :, 2 ) = vertices( :, 2 ) * app.dyEditField.Value;
                
                % parameters
                ele_type = app.LinearEditField.Value;
                precision_nodecoor = app.PrecisionofNodeCoordinatesEditField.Value;
                
                % get node coordinares and elements from mesh
                [ nodecoor_list, ~, ele_cell ] = getNodeEle( ...
                                            vertices, app.ele, app.tnum );
                
                % write inp file (Abaqus)
                % print as multiple sections
                printInp_multiSect( nodecoor_list, ele_cell, ele_type, ...
                                    precision_nodecoor, path_file_name );

                % ------------------------------------------------------------
                % setup
                path_name = app.dir_name;
                path_file_name = [ path_name, filesep, 'test_quadratic.inp' ];
                % ------------------------------------------------------------
                % export quadratic element
                % scale node coordinates
                vertices = app.vert2;
                vertices( :, 1 ) = vertices( :, 1 ) * app.dxEditField.Value;
                vertices( :, 2 ) = vertices( :, 2 ) * app.dyEditField.Value;
                
                % parameters
                ele_type = app.QuadraticEditField.Value;
                precision_nodecoor = app.PrecisionofNodeCoordinatesEditField.Value;
                
                % get node coordinares and elements from mesh
                [ nodecoor_list, ~, ele_cell ] = getNodeEle( ...
                                            vertices, app.ele2, app.tnum );
                
                % write inp file (Abaqus)
                % print as multiple sections
                printInp_multiSect( nodecoor_list, ele_cell, ele_type, ...
                                    precision_nodecoor, path_file_name );

                % ------------------------------------------------------------
                % show status
                pause(0.5)
                app.StatusLamp_ExpInp_Sect.Color = 'green';
                % ------------------------------------------------------------
            catch ME
	            app.StatusLamp_ExpInp_Sect.Color = 'red';
	            uialert( app.UIFigure, getReport(ME), ...
			            "Error Message", "Interpreter", "html" );
            end
        end

        % Callback function
        function ExportasinpfileMultipartsButtonPushed(app, event)
            try
                % ------------------------------------------------------------
                app.StatusLamp_ExpInp_Part.Color = 'blue';
                % Pause to ensure UI updates
                drawnow;
    
                % ------------------------------------------------------------
                % check input
                if isempty(app.dir_name)
                    error("Please select the export folder")
                end
                
                if isempty(app.vert) || isempty(app.ele) || isempty(app.tnum)
                    error("Meshing result not available")
                end

                % ------------------------------------------------------------
                % setup
                path_name = app.dir_name;
                path_file_name = [ path_name, filesep, 'test_multi_parts.inp' ];
                
                % scale node coordinates
                vertices = app.vert;
                vertices( :, 1 ) = vertices( :, 1 ) * app.dxEditField.Value;
                vertices( :, 2 ) = vertices( :, 2 ) * app.dyEditField.Value;
                
                % parameters
                ele_type = app.LinearEditField.Value;
                precision_nodecoor = app.PrecisionofNodeCoordinatesEditField.Value;
                
                % get node coordinares and elements from mesh
                [ ~, nodecoor_cell, ele_cell ] = getNodeEle( ...
                                            vertices, app.ele, app.tnum );
                
                % write inp file (Abaqus)
                % print as multiple sections
                printInp_multiPart( nodecoor_cell, ele_cell, ele_type, ...
                                    precision_nodecoor, path_file_name );
                
                % ------------------------------------------------------------
                % show status
                pause(0.5)
                app.StatusLamp_ExpInp_Part.Color = 'green';
                % ------------------------------------------------------------
            catch ME
	            app.StatusLamp_ExpInp_Part.Color = 'red';
	            uialert( app.UIFigure, getReport(ME), ...
			            "Error Message", "Interpreter", "html" );
            end
        end

        % Button pushed function: OpenTutorialButton
        function OpenTutorialButtonPushed(app, event)
            winopen("Im2mesh_GUI Tutorial.pdf")
        end

        % Button pushed function: LocategmshexeButton
        function LocategmshexeButtonPushed(app, event)
            [file,location] = uigetfile('*.*');

            % Make sure user didn't cancel uigetdir dialog
            if ischar(file)
                app.path_to_gmsh = [location file];
	            app.gmshLocationEditField.Value = app.path_to_gmsh;
            end
        end

        % Button pushed function: MeshingButton_4
        function MeshingButton_4Pushed(app, event)
            % Gmsh
            try
                % ------------------------------------------------------------
                app.StatusLamp_Gmsh.Color = 'blue';
                % Pause to ensure UI updates
                drawnow;
                %--------------------------------------------------------------
                % check input app.boundsSimpfied
                if isempty( app.boundsSimpfied )
                    error("Please simplify boundaries before meshing. " + ...
                        "If your boundaries don't need simplification, " + ...
                        "set the value of parameter tolerance to 0." );
                end
    
                % check input
                if isempty(app.path_to_gmsh)
                    error("Please locate gmsh.exe before meshing.");
                end
                %--------------------------------------------------------------
                % select material phases that need meshing
                t = app.UITable.DisplayData;
                bounds = app.boundsSimpfied( t.tf_need_mesh );
                
                % prepare data
                [ poly_node, poly_edge ] = getPolyNodeEdge( bounds );
                
                % Use function regroup to organize cell array poly_node, 
                % poly_edge into array node, edge.
                [ node, edge, part ] = regroup( poly_node, poly_edge );
    
                % Delaunay triangulation in 2D using subfunction deltri1
                [vertex,~,tria,label] = deltri1( node, edge, node, edge, part );
    
                % convert to loops
                num_phase = length( unique(label) );
                phaseLoops = cell( 1, num_phase );
                
                for i = 1: num_phase
                    triaP = tria( label==i, : );
                    % triangular mesh of one phase to surface loop cell
                    phaseLoops{i} = triaPha2loop(triaP, vertex, edge);
                end
                
                %--------------------------------------------------------------
                % rename variables to consistent with gmsh
                point = vertex;
                line = edge;
                C = phaseLoops;
                
                opt.sizeMin = app.MeshSizeMinEditField.Value;
                opt.sizeMax = app.MeshSizeMaxEditField.Value;
                opt.algthm = app.AlgorithmSpinner.Value;
                opt.recombAll = app.RecombineAllSpinner.Value;
                opt.recombAlgthm = app.RecombinationAlgorithmSpinner.Value;
                opt.eleOrder = app.ElementOrderSpinner.Value;
                path_to_geo = 'gmshTemp.geo';
                
                printGeo( C, point, line, opt, path_to_geo );
                
                path_to_m = 'gmshTemp.m';
                v = 3;  % verbosity
    
                str=sprintf('"%s" "%s" -o "%s" -v %i -save', ... 
			                app.path_to_gmsh, path_to_geo, path_to_m, v );
                system(str);
                
                evalin('base', path_to_m(1:end-2) );  % m file
                temp = evalin('base','msh');    % bbb is workspace variable
                app.meshG = temp;
                %--------------------------------------------------------------
                app.vert2 = []; app.ele2 = [];
    
                if isfield(app.meshG, 'TRIANGLES') && ~isfield(app.meshG, 'QUADS')
                    app.vert = app.meshG.POS(:,1:2);
                    app.ele = app.meshG.TRIANGLES(:,1:end-1);
                    app.tnum = app.meshG.TRIANGLES(:,end);
                    % flip for correct signs
                    area = triarea( app.vert, app.ele );
                    app.ele(area<0.,:) = app.ele( area<0., [1,3,2] );
    
                    % show mesh quality
                    q_score = triscr2( app.vert, app.ele );
                    app.QMeanEditField.Value = mean( q_score );
    
                elseif isfield(app.meshG, 'TRIANGLES6') && ~isfield(app.meshG, 'QUADS9')
                    app.vert = app.meshG.POS(:,1:2);
                    app.ele = app.meshG.TRIANGLES6(:,1:end-1);
                    app.tnum = app.meshG.TRIANGLES6(:,end);
                    % flip for correct signs
                    area = triarea( app.vert, app.ele );
                    app.ele(area<0.,:) = app.ele( area<0., [1,3,2,6,5,4] );
    
                    % show mesh quality
                    q_score = triscr2( app.vert, app.ele );
                    app.QMeanEditField.Value = mean( q_score );
    
                elseif isfield(app.meshG, 'QUADS')
                    app.vert = app.meshG.POS(:,1:2);
                    app.ele = app.meshG.QUADS(:,1:end-1);
                    app.tnum = app.meshG.QUADS(:,end);
    
                    % show mesh quality
                    q_score = MeshQualityQuads( app.ele, app.vert(:,1), app.vert(:,2) );
                    app.QMeanEditField.Value = mean( q_score );

                elseif isfield(app.meshG, 'QUADS9')
                    app.vert = app.meshG.POS(:,1:2);
                    app.ele = app.meshG.QUADS9(:,1:4);
                    app.tnum = app.meshG.QUADS9(:,end);
    
                    % show mesh quality
                    q_score = MeshQualityQuads( app.ele, app.vert(:,1), app.vert(:,2) );
                    app.QMeanEditField.Value = mean( q_score );
                end
                
                % show num of element
                app.NumberofElementsEditField.Value = size( app.ele, 1 );
    
                plotMeshes(app, app.UIAxes_Mesh )
                % ------------------------------------------------------------
                % show status
                pause(0.5)
                app.StatusLamp_Gmsh.Color = 'green';
                % ------------------------------------------------------------
            catch ME
                app.StatusLamp_Gmsh.Color = 'red';
	            uialert( app.UIFigure, getReport(ME), ...
			            "Error Message", "Interpreter", "html" );
            end
        end

        % Button pushed function: ExporttoFileButton
        function ExporttoFileButtonPushed(app, event)
            % Gmsh
            try
                % ------------------------------------------------------------
                app.StatusLamp_GmshExp.Color = 'blue';
                % Pause to ensure UI updates
                drawnow;
                %--------------------------------------------------------------
                % check input
                if isempty(app.meshG)
                    error("Please meshing before export.");
                end
                %--------------------------------------------------------------
                path_to_geo = 'gmshTemp.geo';
                suffix = app.FileNameSuffixforOutputEditField.Value;
                path_to_file = ['gmshTemp.', suffix ] ;
                v = 1;  % verbosity
                
                str=sprintf('"%s" "%s" -o "%s" -v %i -save', ... 
		                    app.path_to_gmsh, path_to_geo, path_to_file, v );
                system(str);
                
                % ------------------------------------------------------------
                % show status
                pause(0.5)
                app.StatusLamp_GmshExp.Color = 'green';
                % ------------------------------------------------------------
            catch ME
                app.StatusLamp_GmshExp.Color = 'red';
	            uialert( app.UIFigure, getReport(ME), ...
			            "Error Message", "Interpreter", "html" );
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Im2mesh GUI';
            app.Panel = uipanel(app.UIFigure);
            app.MeaningofStatusColorLabel = uilabel(app.Panel);
            app.MeaningofStatusColorLabel.Text = 'Meaning of Status Color';
            app.StatusLamp_4Label_2 = uilabel(app.Panel);
            app.StatusLamp_4Label_2.Text = 'Un-run';
            app.StatusLamp_1 = uilamp(app.Panel);
            app.StatusLamp_4Label_3 = uilabel(app.Panel);
            app.StatusLamp_4Label_3.Text = 'Running';
            app.StatusLamp_2 = uilamp(app.Panel);
            app.StatusLamp_4Label_4 = uilabel(app.Panel);
            app.StatusLamp_4Label_4.Text = 'Completed';
            app.StatusLamp_3 = uilamp(app.Panel);
            app.StatusLamp_4Label_5 = uilabel(app.Panel);
            app.StatusLamp_4Label_5.Text = 'Error';
            app.StatusLamp_4 = uilamp(app.Panel);
            app.Step1ImportImagePanel = uipanel(app.UIFigure);
            app.Step1ImportImagePanel.Title = 'Step 1. Import Image';
            app.ImportfromFolderButton = uibutton(app.Step1ImportImagePanel, 'push');
            app.ImportfromFolderButton.ButtonPushedFcn = createCallbackFcn(app, @ImportfromFolderButtonPushed, true);
            app.ImportfromFolderButton.Text = 'Import from Folder';
            app.NumberofPhasesinLoadedImageEditFieldLabel = uilabel(app.Step1ImportImagePanel);
            app.NumberofPhasesinLoadedImageEditFieldLabel.Text = 'Number of Phases in Loaded Image';
            app.NumberofPhasesinLoadedImageEditField = uieditfield(app.Step1ImportImagePanel, 'numeric');
            app.NumberofPhasesinLoadedImageEditField.Editable = 'off';
            app.kumamonButton = uibutton(app.Step1ImportImagePanel, 'push');
            app.kumamonButton.ButtonPushedFcn = createCallbackFcn(app, @kumamonButtonPushed, true);
            app.kumamonButton.Text = 'kumamon';
            app.orLabel = uilabel(app.Step1ImportImagePanel);
            app.orLabel.Text = 'or';
            app.SimpleButton = uibutton(app.Step1ImportImagePanel, 'push');
            app.SimpleButton.ButtonPushedFcn = createCallbackFcn(app, @SimpleButtonPushed, true);
            app.SimpleButton.Text = 'Simple';
            app.CircleButton = uibutton(app.Step1ImportImagePanel, 'push');
            app.CircleButton.ButtonPushedFcn = createCallbackFcn(app, @CircleButtonPushed, true);
            app.CircleButton.Text = 'Circle';
            app.PhasesButton = uibutton(app.Step1ImportImagePanel, 'push');
            app.PhasesButton.ButtonPushedFcn = createCallbackFcn(app, @PhasesButtonPushed, true);
            app.PhasesButton.Text = '7 Phases';
            app.LoadExampleImagesLabel = uilabel(app.Step1ImportImagePanel);
            app.LoadExampleImagesLabel.Text = 'Load Example Images';
            app.ShapeButton = uibutton(app.Step1ImportImagePanel, 'push');
            app.ShapeButton.ButtonPushedFcn = createCallbackFcn(app, @ShapeButtonPushed, true);
            app.ShapeButton.Text = 'Shape';
            app.PepperButton = uibutton(app.Step1ImportImagePanel, 'push');
            app.PepperButton.ButtonPushedFcn = createCallbackFcn(app, @PepperButtonPushed, true);
            app.PepperButton.Text = 'Pepper';
            app.Step2ImageSegmentationOptionalPanel = uipanel(app.UIFigure);
            app.Step2ImageSegmentationOptionalPanel.Title = 'Step 2. Image Segmentation (Optional)';
            app.SegmentationOtsuMethodButton = uibutton(app.Step2ImageSegmentationOptionalPanel, 'push');
            app.SegmentationOtsuMethodButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentationOtsuMethodButtonPushed, true);
            app.SegmentationOtsuMethodButton.Text = 'Segmentation (Otsu Method)';
            app.howmanyphasesinyourimageLabel = uilabel(app.Step2ImageSegmentationOptionalPanel);
            app.howmanyphasesinyourimageLabel.Text = 'Number of Phases (Your Target)';
            app.NumberofPhasesYourTargetEditField = uieditfield(app.Step2ImageSegmentationOptionalPanel, 'numeric');
            app.NumberofPhasesYourTargetEditField.Limits = [2 Inf];
            app.NumberofPhasesYourTargetEditField.RoundFractionalValues = 'on';
            app.NumberofPhasesYourTargetEditField.Value = 2;
            app.StatusLampLabel_2 = uilabel(app.Step2ImageSegmentationOptionalPanel);
            app.StatusLampLabel_2.Text = 'Status';
            app.StatusLamp_Segment = uilamp(app.Step2ImageSegmentationOptionalPanel);
            app.Step6SimplifyBoundaryPanel = uipanel(app.UIFigure);
            app.Step6SimplifyBoundaryPanel.Title = 'Step 6. Simplify Boundary';
            app.ToleranceEditFieldLabel = uilabel(app.Step6SimplifyBoundaryPanel);
            app.ToleranceEditFieldLabel.Text = 'Tolerance';
            app.ToleranceEditField = uieditfield(app.Step6SimplifyBoundaryPanel, 'numeric');
            app.ToleranceEditField.Limits = [0 Inf];
            app.ToleranceEditField.Value = 0.3;
            app.SimplifyButton = uibutton(app.Step6SimplifyBoundaryPanel, 'push');
            app.SimplifyButton.ButtonPushedFcn = createCallbackFcn(app, @SimplifyButtonPushed, true);
            app.SimplifyButton.Text = 'Simplify';
            app.ChooseBoundaryforSimplificationSwitchLabel = uilabel(app.Step6SimplifyBoundaryPanel);
            app.ChooseBoundaryforSimplificationSwitchLabel.Text = 'Choose Boundary for Simplification';
            app.ChooseBoundaryforSimplificationSwitch = uiswitch(app.Step6SimplifyBoundaryPanel, 'slider');
            app.ChooseBoundaryforSimplificationSwitch.Items = {'From Step4', 'From Step5'};
            app.ChooseBoundaryforSimplificationSwitch.Value = 'From Step5';
            app.ThresholdforNumofVerticesEditFieldLabel = uilabel(app.Step6SimplifyBoundaryPanel);
            app.ThresholdforNumofVerticesEditFieldLabel.Text = 'Threshold for Num of Vertices';
            app.ThresholdforNumofVerticesEditField = uieditfield(app.Step6SimplifyBoundaryPanel, 'numeric');
            app.ThresholdforNumofVerticesEditField.Limits = [0 Inf];
            app.ThresholdforNumofVerticesEditField.RoundFractionalValues = 'on';
            app.ThresholdforNumofVerticesEditField.Value = 10;
            app.Label_2 = uilabel(app.Step6SimplifyBoundaryPanel);
            app.Label_2.Text = '➜';
            app.StatusLamp_4Label = uilabel(app.Step6SimplifyBoundaryPanel);
            app.StatusLamp_4Label.Text = 'Status';
            app.StatusLamp_Simpfy = uilamp(app.Step6SimplifyBoundaryPanel);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MESH2DTab = uitab(app.TabGroup);
            app.MESH2DTab.Title = 'MESH2D';
            app.MaxMeshsizeEditFieldLabel = uilabel(app.MESH2DTab);
            app.MaxMeshsizeEditFieldLabel.Text = 'Max Mesh-size';
            app.MaxMeshsizeEditField = uieditfield(app.MESH2DTab, 'numeric');
            app.MaxMeshsizeEditField.Limits = [0.0001 Inf];
            app.MaxMeshsizeEditField.Value = 500;
            app.ScalarGradientlimitforMeshEditFieldLabel = uilabel(app.MESH2DTab);
            app.ScalarGradientlimitforMeshEditFieldLabel.Text = 'Scalar Gradient-limit for Mesh';
            app.ScalarGradientlimitforMeshEditField = uieditfield(app.MESH2DTab, 'numeric');
            app.ScalarGradientlimitforMeshEditField.Limits = [0.0001 Inf];
            app.ScalarGradientlimitforMeshEditField.Value = 0.25;
            app.Hyperlink = uihyperlink(app.MESH2DTab);
            app.Hyperlink.URL = 'https://www.mathworks.com/matlabcentral/fileexchange/25555-mesh2d-delaunay-based-unstructured-mesh-generation';
            app.Hyperlink.Text = 'Mesh-generator developed by Darren Engwirda';
            app.MeshingButton_2 = uibutton(app.MESH2DTab, 'push');
            app.MeshingButton_2.ButtonPushedFcn = createCallbackFcn(app, @MeshingButton_2Pushed, true);
            app.MeshingButton_2.Text = 'Meshing';
            app.ChooseMeshingAlgorithmSwitchLabel = uilabel(app.MESH2DTab);
            app.ChooseMeshingAlgorithmSwitchLabel.Text = 'Choose Meshing Algorithm';
            app.ChooseMeshingAlgorithmSwitch = uiswitch(app.MESH2DTab, 'slider');
            app.ChooseMeshingAlgorithmSwitch.Items = {'Delaunay-refinement', 'Frontal-Delaunay'};
            app.ChooseMeshingAlgorithmSwitch.ItemsData = {'delaunay', 'delfront'};
            app.ChooseMeshingAlgorithmSwitch.Value = 'delaunay';
            app.StatusLamp_6Label = uilabel(app.MESH2DTab);
            app.StatusLamp_6Label.Text = 'Status';
            app.StatusLamp_MESH2D = uilamp(app.MESH2DTab);
            app.generateMeshTab = uitab(app.TabGroup);
            app.generateMeshTab.Title = 'generateMesh';
            app.MeshGrowthRateEditFieldLabel = uilabel(app.generateMeshTab);
            app.MeshGrowthRateEditFieldLabel.Text = 'Mesh Growth Rate';
            app.MeshGrowthRateEditField = uieditfield(app.generateMeshTab, 'numeric');
            app.MeshGrowthRateEditField.Limits = [1 2];
            app.MeshGrowthRateEditField.Value = 1.25;
            app.MaxmeshedgelengthLabel = uilabel(app.generateMeshTab);
            app.MaxmeshedgelengthLabel.Text = 'Max Mesh Edge Length';
            app.MaxMeshEdgeLengthEditField = uieditfield(app.generateMeshTab, 'numeric');
            app.MaxMeshEdgeLengthEditField.Limits = [0.0001 Inf];
            app.MaxMeshEdgeLengthEditField.Value = 500;
            app.MinMeshEdgeLengthEditFieldLabel = uilabel(app.generateMeshTab);
            app.MinMeshEdgeLengthEditFieldLabel.Text = 'Min Mesh Edge Length';
            app.MinMeshEdgeLengthEditField = uieditfield(app.generateMeshTab, 'numeric');
            app.MinMeshEdgeLengthEditField.Limits = [0 Inf];
            app.MinMeshEdgeLengthEditField.Value = 1;
            app.Hyperlink2 = uihyperlink(app.generateMeshTab);
            app.Hyperlink2.URL = 'https://www.mathworks.com/help/pde/ug/pde.pdemodel.generatemesh.html';
            app.Hyperlink2.Text = 'MATLAB built-in function generateMesh()';
            app.MeshingButton = uibutton(app.generateMeshTab, 'push');
            app.MeshingButton.ButtonPushedFcn = createCallbackFcn(app, @MeshingButtonPushed, true);
            app.MeshingButton.Text = 'Meshing';
            app.StatusLamp_5Label = uilabel(app.generateMeshTab);
            app.StatusLamp_5Label.Text = 'Status';
            app.StatusLamp_genMesh = uilamp(app.generateMeshTab);
            app.GmshTab = uitab(app.TabGroup);
            app.GmshTab.Title = 'Gmsh';
            app.LocategmshexeButton = uibutton(app.GmshTab, 'push');
            app.LocategmshexeButton.ButtonPushedFcn = createCallbackFcn(app, @LocategmshexeButtonPushed, true);
            app.LocategmshexeButton.Text = 'Locate gmsh.exe';
            app.gmshLocationEditField = uieditfield(app.GmshTab, 'text');
            app.gmshLocationEditField.Editable = 'off';
            app.MeshSizeMinEditFieldLabel = uilabel(app.GmshTab);
            app.MeshSizeMinEditFieldLabel.Text = 'MeshSizeMin';
            app.MeshSizeMinEditField = uieditfield(app.GmshTab, 'numeric');
            app.MeshSizeMinEditField.Limits = [0 10000];
            app.MeshSizeMinEditField.Value = 0.5;
            app.MeshSizeMaxEditFieldLabel = uilabel(app.GmshTab);
            app.MeshSizeMaxEditFieldLabel.Text = 'MeshSizeMax';
            app.MeshSizeMaxEditField = uieditfield(app.GmshTab, 'numeric');
            app.MeshSizeMaxEditField.Limits = [0 100000];
            app.MeshSizeMaxEditField.Value = 500;
            app.AlgorithmSpinnerLabel = uilabel(app.GmshTab);
            app.AlgorithmSpinnerLabel.Text = 'Algorithm';
            app.AlgorithmSpinner = uispinner(app.GmshTab);
            app.AlgorithmSpinner.Limits = [1 11];
            app.AlgorithmSpinner.Value = 6;
            app.ElementOrderSpinnerLabel = uilabel(app.GmshTab);
            app.ElementOrderSpinnerLabel.Text = 'ElementOrder';
            app.ElementOrderSpinner = uispinner(app.GmshTab);
            app.ElementOrderSpinner.Limits = [1 2];
            app.ElementOrderSpinner.Value = 1;
            app.RecombineAllSpinnerLabel = uilabel(app.GmshTab);
            app.RecombineAllSpinnerLabel.Text = 'RecombineAll';
            app.RecombineAllSpinner = uispinner(app.GmshTab);
            app.RecombineAllSpinner.Limits = [0 1];
            app.RecombinationAlgorithmSpinnerLabel = uilabel(app.GmshTab);
            app.RecombinationAlgorithmSpinnerLabel.Text = 'RecombinationAlgorithm';
            app.RecombinationAlgorithmSpinner = uispinner(app.GmshTab);
            app.RecombinationAlgorithmSpinner.Limits = [0 3];
            app.RecombinationAlgorithmSpinner.Value = 3;
            app.FileNameSuffixforOutputEditFieldLabel = uilabel(app.GmshTab);
            app.FileNameSuffixforOutputEditFieldLabel.Text = 'File Name Suffix for Output';
            app.FileNameSuffixforOutputEditField = uieditfield(app.GmshTab, 'text');
            app.FileNameSuffixforOutputEditField.Value = 'inp';
            app.MeshingButton_4 = uibutton(app.GmshTab, 'push');
            app.MeshingButton_4.ButtonPushedFcn = createCallbackFcn(app, @MeshingButton_4Pushed, true);
            app.MeshingButton_4.Text = 'Meshing';
            app.ExporttoFileButton = uibutton(app.GmshTab, 'push');
            app.ExporttoFileButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttoFileButtonPushed, true);
            app.ExporttoFileButton.Text = 'Export to File';
            app.StatusLamp_5Label_7 = uilabel(app.GmshTab);
            app.StatusLamp_5Label_7.Text = 'Status';
            app.StatusLamp_Gmsh = uilamp(app.GmshTab);
            app.StatusLamp_5Label_8 = uilabel(app.GmshTab);
            app.StatusLamp_5Label_8.Text = 'Status';
            app.StatusLamp_GmshExp = uilamp(app.GmshTab);
            app.pixelMeshTab = uitab(app.TabGroup);
            app.pixelMeshTab.Title = 'pixelMesh';
            app.MeshingButton_3 = uibutton(app.pixelMeshTab, 'push');
            app.MeshingButton_3.ButtonPushedFcn = createCallbackFcn(app, @MeshingButton_3Pushed, true);
            app.MeshingButton_3.Text = 'Meshing';
            app.StatusLamp_5Label_6 = uilabel(app.pixelMeshTab);
            app.StatusLamp_5Label_6.Text = 'Status';
            app.StatusLamp_pixelMesh = uilamp(app.pixelMeshTab);
            app.Hyperlink4 = uihyperlink(app.pixelMeshTab);
            app.Hyperlink4.URL = 'https://www.mathworks.com/matlabcentral/fileexchange/104715-pixelmesh-pixel-based-mesh?s_tid=srchtitle';
            app.Hyperlink4.Text = 'Generate pixel-based quadrilateral mesh';
            app.Step8SelectmeshgeneratorLabel = uilabel(app.UIFigure);
            app.Step8SelectmeshgeneratorLabel.Text = 'Step 8. Select Mesh Generator ';
            app.Step5SmoothBoundaryOptionalPanel = uipanel(app.UIFigure);
            app.Step5SmoothBoundaryOptionalPanel.Title = 'Step 5. Smooth Boundary (Optional)';
            app.SmoothButton = uibutton(app.Step5SmoothBoundaryOptionalPanel, 'push');
            app.SmoothButton.ButtonPushedFcn = createCallbackFcn(app, @SmoothButtonPushed, true);
            app.SmoothButton.Text = 'Smooth';
            app.LambdaEditFieldLabel = uilabel(app.Step5SmoothBoundaryOptionalPanel);
            app.LambdaEditFieldLabel.Text = 'Lambda';
            app.LambdaEditField = uieditfield(app.Step5SmoothBoundaryOptionalPanel, 'numeric');
            app.LambdaEditField.Limits = [0 1];
            app.LambdaEditField.Value = 0.5;
            app.MuEditFieldLabel = uilabel(app.Step5SmoothBoundaryOptionalPanel);
            app.MuEditFieldLabel.Text = 'Mu';
            app.MuEditField = uieditfield(app.Step5SmoothBoundaryOptionalPanel, 'numeric');
            app.MuEditField.Limits = [-1 0];
            app.MuEditField.Value = -0.5;
            app.IterationsEditFieldLabel = uilabel(app.Step5SmoothBoundaryOptionalPanel);
            app.IterationsEditFieldLabel.Text = 'Iterations';
            app.IterationsEditField = uieditfield(app.Step5SmoothBoundaryOptionalPanel, 'numeric');
            app.IterationsEditField.Limits = [0 Inf];
            app.IterationsEditField.RoundFractionalValues = 'on';
            app.IterationsEditField.Value = 100;
            app.ThresholdforNumofTurningPointsEditFieldLabel = uilabel(app.Step5SmoothBoundaryOptionalPanel);
            app.ThresholdforNumofTurningPointsEditFieldLabel.Text = 'Threshold for Num of Turning Points';
            app.ThresholdforNumofTurningPointsEditField = uieditfield(app.Step5SmoothBoundaryOptionalPanel, 'numeric');
            app.ThresholdforNumofTurningPointsEditField.Limits = [0 Inf];
            app.ThresholdforNumofTurningPointsEditField.RoundFractionalValues = 'on';
            app.ThresholdforNumofTurningPointsEditField.Value = 10;
            app.StatusLamp_3Label = uilabel(app.Step5SmoothBoundaryOptionalPanel);
            app.StatusLamp_3Label.Text = 'Status';
            app.StatusLamp_Smooth = uilamp(app.Step5SmoothBoundaryOptionalPanel);
            app.ThresholdforNumofVerticesEditField_2Label = uilabel(app.Step5SmoothBoundaryOptionalPanel);
            app.ThresholdforNumofVerticesEditField_2Label.Text = 'Threshold for Num of Vertices';
            app.ThresholdforNumofVerticesEditField_2 = uieditfield(app.Step5SmoothBoundaryOptionalPanel, 'numeric');
            app.ThresholdforNumofVerticesEditField_2.Limits = [0 Inf];
            app.ThresholdforNumofVerticesEditField_2.RoundFractionalValues = 'on';
            app.ThresholdforNumofVerticesEditField_2.Value = 10;
            app.Step3ExtractBoundariesfromImagePanel = uipanel(app.UIFigure);
            app.Step3ExtractBoundariesfromImagePanel.Title = 'Step 3. Extract Boundaries from Image';
            app.ExtractBoundaryButton = uibutton(app.Step3ExtractBoundariesfromImagePanel, 'push');
            app.ExtractBoundaryButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractBoundaryButtonPushed, true);
            app.ExtractBoundaryButton.Text = 'Extract Boundary';
            app.ChooseInputImagefromLabel = uilabel(app.Step3ExtractBoundariesfromImagePanel);
            app.ChooseInputImagefromLabel.Text = 'Choose Image for Extraction';
            app.ChooseImageforExtractionSwitch = uiswitch(app.Step3ExtractBoundariesfromImagePanel, 'slider');
            app.ChooseImageforExtractionSwitch.Items = {'From Step1', 'From Step2'};
            app.ChooseImageforExtractionSwitch.Value = 'From Step1';
            app.Label = uilabel(app.Step3ExtractBoundariesfromImagePanel);
            app.Label.Text = '➜';
            app.StatusLampLabel = uilabel(app.Step3ExtractBoundariesfromImagePanel);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp_Extract = uilamp(app.Step3ExtractBoundariesfromImagePanel);
            app.Step4SearchControlPointsPanel = uipanel(app.UIFigure);
            app.Step4SearchControlPointsPanel.Title = 'Step 4. Search Control Points';
            app.SearchLabelControlPointsButton = uibutton(app.Step4SearchControlPointsPanel, 'push');
            app.SearchLabelControlPointsButton.ButtonPushedFcn = createCallbackFcn(app, @SearchLabelControlPointsButtonPushed, true);
            app.SearchLabelControlPointsButton.Text = 'Search & Label Control Points';
            app.NeedtoAvoidSharpCornerSwitch = uiswitch(app.Step4SearchControlPointsPanel, 'slider');
            app.NeedtoAvoidSharpCornerSwitch.Items = {'No', 'Yes'};
            app.NeedtoAvoidSharpCornerSwitch.Value = 'No';
            app.NeedtoAvoidSharpCornerSwitchLabel = uilabel(app.Step4SearchControlPointsPanel);
            app.NeedtoAvoidSharpCornerSwitchLabel.Text = 'Need to Avoid Sharp Corner?';
            app.NumberofControlPointsEditFieldLabel = uilabel(app.Step4SearchControlPointsPanel);
            app.NumberofControlPointsEditFieldLabel.Text = 'Number of Control Points';
            app.NumberofControlPointsEditField = uieditfield(app.Step4SearchControlPointsPanel, 'numeric');
            app.NumberofControlPointsEditField.Editable = 'off';
            app.StatusLamp_2Label = uilabel(app.Step4SearchControlPointsPanel);
            app.StatusLamp_2Label.Text = 'Status';
            app.StatusLamp_CtrlP = uilamp(app.Step4SearchControlPointsPanel);
            app.Step9ExportMeshPanel = uipanel(app.UIFigure);
            app.Step9ExportMeshPanel.Title = 'Step 9. Export Mesh';
            app.Panel_2 = uipanel(app.Step9ExportMeshPanel);
            app.ExportasmatfileButton = uibutton(app.Panel_2, 'push');
            app.ExportasmatfileButton.ButtonPushedFcn = createCallbackFcn(app, @ExportasmatfileButtonPushed, true);
            app.ExportasmatfileButton.Text = 'Export as mat file ';
            app.StatusLamp_5Label_2 = uilabel(app.Panel_2);
            app.StatusLamp_5Label_2.Text = 'Status';
            app.StatusLamp_ExpMat = uilamp(app.Panel_2);
            app.forMATLABLabel = uilabel(app.Panel_2);
            app.forMATLABLabel.Text = '(for MATLAB)';
            app.ExportasExcelfileButton = uibutton(app.Panel_2, 'push');
            app.ExportasExcelfileButton.ButtonPushedFcn = createCallbackFcn(app, @ExportasExcelfileButtonPushed, true);
            app.ExportasExcelfileButton.Text = 'Export as Excel file';
            app.StatusLamp_5Label_5 = uilabel(app.Panel_2);
            app.StatusLamp_5Label_5.Text = 'Status';
            app.StatusLamp_ExpExcel = uilamp(app.Panel_2);
            app.Panel_3 = uipanel(app.Step9ExportMeshPanel);
            app.dxEditFieldLabel = uilabel(app.Panel_3);
            app.dxEditFieldLabel.Text = 'dx';
            app.dxEditField = uieditfield(app.Panel_3, 'numeric');
            app.dxEditField.Limits = [2e-16 Inf];
            app.dxEditField.Value = 1;
            app.dyEditFieldLabel = uilabel(app.Panel_3);
            app.dyEditFieldLabel.Text = 'dy';
            app.dyEditField = uieditfield(app.Panel_3, 'numeric');
            app.dyEditField.Limits = [2e-16 Inf];
            app.dyEditField.Value = 1;
            app.PrecisionofNodeCoordinatesEditFieldLabel = uilabel(app.Panel_3);
            app.PrecisionofNodeCoordinatesEditFieldLabel.Text = 'Precision of Node Coordinates';
            app.PrecisionofNodeCoordinatesEditField = uieditfield(app.Panel_3, 'numeric');
            app.PrecisionofNodeCoordinatesEditField.Limits = [0 100];
            app.PrecisionofNodeCoordinatesEditField.Value = 8;
            app.ScaleofaPixelLabel = uilabel(app.Panel_3);
            app.ScaleofaPixelLabel.Text = 'Scale of a Pixel:';
            app.ExportasbdffileButton = uibutton(app.Panel_3, 'push');
            app.ExportasbdffileButton.ButtonPushedFcn = createCallbackFcn(app, @ExportasbdffileButtonPushed, true);
            app.ExportasbdffileButton.Text = 'Export as bdf file ';
            app.ElementTypeforinpfileLabel = uilabel(app.Panel_3);
            app.ElementTypeforinpfileLabel.Text = 'Linear';
            app.LinearEditField = uieditfield(app.Panel_3, 'text');
            app.LinearEditField.Value = 'CPS3';
            app.ExportasbdforinpfileLabel = uilabel(app.Panel_3);
            app.ExportasbdforinpfileLabel.Text = 'Export as bdf or inp file';
            app.StatusLamp_5Label_3 = uilabel(app.Panel_3);
            app.StatusLamp_5Label_3.Text = 'Status';
            app.StatusLamp_ExpBdf = uilamp(app.Panel_3);
            app.NastranbulkdataforCOMSOLLabel = uilabel(app.Panel_3);
            app.NastranbulkdataforCOMSOLLabel.Text = 'Nastran bulk data for COMSOL';
            app.ExportasinpfileMultisectionsButton = uibutton(app.Panel_3, 'push');
            app.ExportasinpfileMultisectionsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportasinpfileMultisectionsButtonPushed, true);
            app.ExportasinpfileMultisectionsButton.Text = 'Export as inp file (Multi-sections)';
            app.StatusLamp_5Label_4 = uilabel(app.Panel_3);
            app.StatusLamp_5Label_4.Text = 'Status';
            app.StatusLamp_ExpInp_Sect = uilamp(app.Panel_3);
            app.ElementTypeLabel = uilabel(app.Panel_3);
            app.ElementTypeLabel.Text = 'Element Type:';
            app.QuadraticEditFieldLabel = uilabel(app.Panel_3);
            app.QuadraticEditFieldLabel.Text = 'Quadratic';
            app.QuadraticEditField = uieditfield(app.Panel_3, 'text');
            app.QuadraticEditField.Value = 'CPS6';
            app.Panel_4 = uipanel(app.Step9ExportMeshPanel);
            app.SelecttheExportFolderButton = uibutton(app.Panel_4, 'push');
            app.SelecttheExportFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelecttheExportFolderButtonPushed, true);
            app.SelecttheExportFolderButton.Text = 'Select the Export Folder';
            app.DirectoryEditField = uieditfield(app.Panel_4, 'text');
            app.DirectoryEditField.Editable = 'off';
            app.Step7SelectPhaseOptionalPanel = uipanel(app.UIFigure);
            app.Step7SelectPhaseOptionalPanel.Title = 'Step 7. Select Phase (Optional)';
            app.UITable = uitable(app.Step7SelectPhaseOptionalPanel);
            app.UITable.ColumnName = {'Grayscale'; 'Select for Meshing'; 'Pixel Area %'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [true true true true];
            app.UITable.ColumnEditable = [false true false];
            app.SimplifiedBoundariesPanel = uipanel(app.UIFigure);
            app.SimplifiedBoundariesPanel.Title = 'Simplified Boundaries';
            app.UIAxes_Simpfy = uiaxes(app.SimplifiedBoundariesPanel);
            zlabel(app.UIAxes_Simpfy, 'Z')
            app.UIAxes_Simpfy.XTick = [];
            app.UIAxes_Simpfy.YTick = [];
            app.ComparewithPristineBoundaryButton = uibutton(app.SimplifiedBoundariesPanel, 'push');
            app.ComparewithPristineBoundaryButton.ButtonPushedFcn = createCallbackFcn(app, @ComparewithPristineBoundaryButtonPushed, true);
            app.ComparewithPristineBoundaryButton.Text = 'Compare with Pristine Boundary';
            app.ViewBoundaryinNewWindowButton_2 = uibutton(app.SimplifiedBoundariesPanel, 'push');
            app.ViewBoundaryinNewWindowButton_2.ButtonPushedFcn = createCallbackFcn(app, @ViewBoundaryinNewWindowButton_2Pushed, true);
            app.ViewBoundaryinNewWindowButton_2.Text = 'View Boundary in New Window';
            app.NumberofVerticesEditField_2Label = uilabel(app.SimplifiedBoundariesPanel);
            app.NumberofVerticesEditField_2Label.Text = 'Number of Vertices';
            app.NumberofVerticesEditField_3 = uieditfield(app.SimplifiedBoundariesPanel, 'numeric');
            app.NumberofVerticesEditField_3.Editable = 'off';
            app.MeshPanel = uipanel(app.UIFigure);
            app.MeshPanel.Title = 'Mesh';
            app.UIAxes_Mesh = uiaxes(app.MeshPanel);
            zlabel(app.UIAxes_Mesh, 'Z')
            app.UIAxes_Mesh.XTick = [];
            app.UIAxes_Mesh.YTick = [];
            app.EvaluateMeshQualityButton = uibutton(app.MeshPanel, 'push');
            app.EvaluateMeshQualityButton.ButtonPushedFcn = createCallbackFcn(app, @EvaluateMeshQualityButtonPushed, true);
            app.EvaluateMeshQualityButton.Text = 'Evaluate Mesh Quality';
            app.ViewMeshinNewWindowButton = uibutton(app.MeshPanel, 'push');
            app.ViewMeshinNewWindowButton.ButtonPushedFcn = createCallbackFcn(app, @ViewMeshinNewWindowButtonPushed, true);
            app.ViewMeshinNewWindowButton.Text = 'View Mesh in New Window';
            app.NumberofElementsEditFieldLabel = uilabel(app.MeshPanel);
            app.NumberofElementsEditFieldLabel.Text = 'Number of Elements';
            app.NumberofElementsEditField = uieditfield(app.MeshPanel, 'numeric');
            app.NumberofElementsEditField.Editable = 'off';
            app.QMeanEditFieldLabel = uilabel(app.MeshPanel);
            app.QMeanEditFieldLabel.Text = 'Q Mean';
            app.QMeanEditField = uieditfield(app.MeshPanel, 'numeric');
            app.QMeanEditField.Editable = 'off';
            app.SmoothedBoundariesPanel = uipanel(app.UIFigure);
            app.SmoothedBoundariesPanel.Title = 'Smoothed Boundaries';
            app.UIAxes_Smooth = uiaxes(app.SmoothedBoundariesPanel);
            zlabel(app.UIAxes_Smooth, 'Z')
            app.UIAxes_Smooth.XTick = [];
            app.UIAxes_Smooth.YTick = [];
            app.ComparewithPristineBoundaryButton_2 = uibutton(app.SmoothedBoundariesPanel, 'push');
            app.ComparewithPristineBoundaryButton_2.ButtonPushedFcn = createCallbackFcn(app, @ComparewithPristineBoundaryButton_2Pushed, true);
            app.ComparewithPristineBoundaryButton_2.Text = 'Compare with Pristine Boundary';
            app.ViewBoundaryinNewWindowButton = uibutton(app.SmoothedBoundariesPanel, 'push');
            app.ViewBoundaryinNewWindowButton.ButtonPushedFcn = createCallbackFcn(app, @ViewBoundaryinNewWindowButtonPushed, true);
            app.ViewBoundaryinNewWindowButton.Text = 'View Boundary in New Window';
            app.ImagePanel = uipanel(app.UIFigure);
            app.ImagePanel.Title = 'Image';
            app.ImageAxes = uiaxes(app.ImagePanel);
            app.ImageAxes.XTick = [];
            app.ImageAxes.XTickLabel = {'[ ]'};
            app.ImageAxes.YTick = [];
            app.HeightEditFieldLabel = uilabel(app.ImagePanel);
            app.HeightEditFieldLabel.Text = 'Height';
            app.HeightEditField = uieditfield(app.ImagePanel, 'numeric');
            app.HeightEditField.Editable = 'off';
            app.WidthEditFieldLabel = uilabel(app.ImagePanel);
            app.WidthEditFieldLabel.Text = 'Width';
            app.WidthEditField = uieditfield(app.ImagePanel, 'numeric');
            app.WidthEditField.Editable = 'off';
            app.FileNameEditFieldLabel = uilabel(app.ImagePanel);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.ImagePanel, 'text');
            app.FileNameEditField.Editable = 'off';
            app.SegmentedImagePanel = uipanel(app.UIFigure);
            app.SegmentedImagePanel.Title = 'Segmented Image';
            app.ImageAxes_2 = uiaxes(app.SegmentedImagePanel);
            app.ImageAxes_2.XTick = [];
            app.ImageAxes_2.XTickLabel = {'[ ]'};
            app.ImageAxes_2.YTick = [];
            app.NumberofPhasesEditFieldLabel = uilabel(app.SegmentedImagePanel);
            app.NumberofPhasesEditFieldLabel.Text = 'Number of Phases';
            app.NumberofPhasesEditField = uieditfield(app.SegmentedImagePanel, 'numeric');
            app.NumberofPhasesEditField.Editable = 'off';
            app.PristineBoundariesPanel = uipanel(app.UIFigure);
            app.PristineBoundariesPanel.Title = 'Pristine Boundaries';
            app.UIAxes_PolyO = uiaxes(app.PristineBoundariesPanel);
            zlabel(app.UIAxes_PolyO, 'Z')
            app.UIAxes_PolyO.XTick = [];
            app.UIAxes_PolyO.YTick = [];
            app.NumberofVerticesEditFieldLabel = uilabel(app.PristineBoundariesPanel);
            app.NumberofVerticesEditFieldLabel.Text = 'Number of Vertices';
            app.NumberofVerticesEditField = uieditfield(app.PristineBoundariesPanel, 'numeric');
            app.NumberofVerticesEditField.Editable = 'off';
            app.ViewBoundaryinNewWindowButton_3 = uibutton(app.PristineBoundariesPanel, 'push');
            app.ViewBoundaryinNewWindowButton_3.ButtonPushedFcn = createCallbackFcn(app, @ViewBoundaryinNewWindowButton_3Pushed, true);
            app.ViewBoundaryinNewWindowButton_3.Text = 'View Boundary in New Window';
            app.Panel_5 = uipanel(app.UIFigure);
            app.Im2mesh_GUILabel = uilabel(app.Panel_5);
            app.Im2mesh_GUILabel.Text = 'Im2mesh_GUI';
            app.v22020192025Label = uilabel(app.Panel_5);
            app.v22020192025Label.Text = 'v2.20 (2019-2025)';
            app.Generatefiniteelementmeshbasedon2DmultiphaseimageLabel = uilabel(app.Panel_5);
            app.Generatefiniteelementmeshbasedon2DmultiphaseimageLabel.Text = 'Generate finite element mesh based on 2D multi-phase image';
            app.DevelopedbyJiexianMamjx0799gmailcomLabel = uilabel(app.Panel_5);
            app.DevelopedbyJiexianMamjx0799gmailcomLabel.Text = 'Developed by Jiexian Ma (mjx0799@gmail.com)';
            app.OpenTutorialButton = uibutton(app.Panel_5, 'push');
            app.OpenTutorialButton.ButtonPushedFcn = createCallbackFcn(app, @OpenTutorialButtonPushed, true);
            app.OpenTutorialButton.Text = 'Open Tutorial';
            app.CiteAsTextAreaLabel = uilabel(app.Panel_5);
            app.CiteAsTextAreaLabel.Text = 'Cite As';
            app.CiteAsTextArea = uitextarea(app.Panel_5);
            app.CiteAsTextArea.Value = {'Ma, J., & Li, Y. (2025). Im2mesh: A MATLAB/Octave package for generating finite element mesh based on 2D multi-phase image (2.1.5). Zenodo. https://doi.org/10.5281/zenodo.14847059'};
            app.Hyperlink3 = uihyperlink(app.Panel_5);
            app.Hyperlink3.URL = 'https://www.linkedin.com/in/jiexian-ma/';
            app.Hyperlink3.Text = 'Linkedin';
            app.Hyperlink3_2 = uihyperlink(app.Panel_5);
            app.Hyperlink3_2.URL = 'https://github.com/mjx888/im2mesh';
            app.Hyperlink3_2.Text = 'Project website';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Im2meshGUI_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end